classdef MAPPER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClassifyInterveinsButton        matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        WingareammLabel                 matlab.ui.control.Label
        WingareammTextArea              matlab.ui.control.TextArea
        TrichomedensitycountmmLabel     matlab.ui.control.Label
        TrichomedensitycountmmTextArea  matlab.ui.control.TextArea
        UIAxes3                         matlab.ui.control.UIAxes
        LoadRawImageButton              matlab.ui.control.Button
        LoadSegmentationMaskButton      matlab.ui.control.Button
        APaxisButton                    matlab.ui.control.Button
        dL3L4Button                     matlab.ui.control.Button
        PDaxisButton                    matlab.ui.control.Button
        SizeofwingfilterEditFieldLabel  matlab.ui.control.Label
        SizeofwingfilterEditField       matlab.ui.control.NumericEditField
        SizeofinterveinfilterEditFieldLabel  matlab.ui.control.Label
        SizeofinterveinfilterEditField  matlab.ui.control.NumericEditField
        ThresholddistancetrichomeEditFieldLabel  matlab.ui.control.Label
        ThresholddistancetrichomeEditField  matlab.ui.control.NumericEditField
        NumberofharmonicsEditFieldLabel  matlab.ui.control.Label
        NumberofharmonicsEditField      matlab.ui.control.NumericEditField
        BatchProcessingButton           matlab.ui.control.Button
        IIInputparametersLabel          matlab.ui.control.Label
        GlobalstatisticsLabel           matlab.ui.control.Label
        UseroperationLabel              matlab.ui.control.Label
        BiologicalaxesLabel             matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        BiologicalaxesLabel_2           matlab.ui.control.Label
        IIIIndividualwinganalysisLabel  matlab.ui.control.Label
        IVBatchprocessingLabel          matlab.ui.control.Label
        ConversionfactorEditFieldLabel  matlab.ui.control.Label
        ConversionfactorEditField       matlab.ui.control.NumericEditField
        UseroperationLabel_2            matlab.ui.control.Label
        MulticellularAnalysisProcessingPlatformLabel  matlab.ui.control.Label
        forExperimentalResearchLabel    matlab.ui.control.Label
        forimagingat4xLabel             matlab.ui.control.Label
        forimagingat4xLabel_2           matlab.ui.control.Label
        forimagingat4xLabel_3           matlab.ui.control.Label
        between1020Label                matlab.ui.control.Label
        micronsperpixelLabel            matlab.ui.control.Label
        IndividualandbatchLabel         matlab.ui.control.Label
        IndividualandbatchLabel_2       matlab.ui.control.Label
        IndividualandbatchLabel_3       matlab.ui.control.Label
        IndividualandbatchLabel_4       matlab.ui.control.Label
        BatchonlyLabel                  matlab.ui.control.Label
        IWingpreprocessingLabel         matlab.ui.control.Label
        RemovewinghingeButton           matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        FullProcessButton               matlab.ui.control.Button
        IndividualinterveinoutputLabel  matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        TroubleshootingLabel            matlab.ui.control.Label
        SeeGitHubPageButton             matlab.ui.control.Button
        OutputspreadsheetsavenameEditFieldLabel  matlab.ui.control.Label
        OutputspreadsheetsavenameEditField  matlab.ui.control.EditField
        OpenWingMeasurementsButton      matlab.ui.control.Button
        OpenEFDCoefficientsButton       matlab.ui.control.Button
        OpenManualButton                matlab.ui.control.Button
        SubmitTroubleshootInquiryButton  matlab.ui.control.Button
        ProcessingapplicabilityLabel    matlab.ui.control.Label
        DefaultvalueLabel               matlab.ui.control.Label
        DefaultvalueLabel_2             matlab.ui.control.Label
        DefaultvalueLabel_3             matlab.ui.control.Label
        DefaultvalueLabel_4             matlab.ui.control.Label
        ScalinginterpretationLabel      matlab.ui.control.Label
        EnableuniqueoutputlabelCheckBox  matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        LengthmmLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % FH EDIT: Startup function to allow demo and assure correct
           % paths are called before implementation
           
           % ADD FOLDERS AND SUBFOLDERS NEEDED ON STARTUP
            addpath(genpath('dependencies')); 
            addpath(genpath('demo_hingeRemoval_Uncropped'));
            addpath(genpath('demo_batchProcessing'));
            addpath(genpath('demo_individualProcessing'));
            addpath(genpath('GUI_Logos'));
            addpath(genpath('user_Data'));
            addpath(genpath('batch_output_files'));
            addpath(genpath('individual_output_files'));
            global individualLabel
            
            
            app.Image.ImageSource = 'GUI_Logos/logo.png';
            app.Image_2.ImageSource = 'GUI_Logos/GitHub-logo.png';
            
           % CHECK FOR TOOLBOXES
            are_installed = true;
            current_addons = matlab.addons.installedAddons;
            idx_computer_vision = find(contains(current_addons.Name, 'Computer Vision Toolbox'));
            idx_image_processing = find(contains(current_addons.Name, 'Image Processing Toolbox'));
            idx_machine_learning = find(contains(current_addons.Name, 'Statistics and Machine Learning Toolbox')); 
            if isempty(idx_computer_vision)
                disp('You do not have the Computer Vision Toolbox installed');
                are_installed = false;
            end
            if isempty(idx_image_processing)
                disp('You do not have the Image Processing Toolbox installed');
                are_installed = false;
            end
            if isempty(idx_machine_learning)
                disp('You do not have the Statistics and Machine Learning Toolbox installed');
                are_installed = false;
            end
            
            if ~are_installed
                disp('You are missing at least one toolbox to run MAPPER');
                disp('Please see the user documentation for instructions on how to install them');
            end
            
           % RUN A SAMPLE IMAGE
            rawImage = imread("demo_individualProcessing\57151_1_rotated.tif");
            if app.EnableuniqueoutputlabelCheckBox.Value
                individualLabel = "57151_1_rotated_Label.png";
            else
                individualLabel = "imageOutput.png";
            end
            imshow(rawImage,'Parent',app.UIAxes);
            save('individual_output_files/rawImage.mat','rawImage');
            segmentationMask = imread("demo_individualProcessing\57151_1_rotated_Simple Segmentation.png"); 
            save('individual_output_files/segmentationMask.mat','segmentationMask');
            ClassifyInterveinsButtonPushed(app, @ClassifyinterveinsButtonPushed);
            APaxisButtonPushed(app, @APaxisButtonPushed);
        end

        % Button pushed function: ClassifyInterveinsButton
        function ClassifyInterveinsButtonPushed(app, event)
            global individualLabel
            format long g;
            format compact;
            addpath('dependencies');
            
            load('individual_output_files/rawImage.mat');
            load('individual_output_files/segmentationMask.mat');
            
            %% Parameters
            morphFilterSizeIntervein = app.SizeofinterveinfilterEditField.Value;
            morphFilterSizwWing = app.SizeofwingfilterEditField.Value;
            thresholdTrichome = app.ThresholddistancetrichomeEditField.Value;
            magWing = app.ConversionfactorEditField.Value;
            windowSizeDensityPlot = 32;
            labelName = individualLabel;

            %% Morphological operation to filter the segmentation mask of interveins
            % Function details: interveinMorphFilter(arg1, arg2);
            % arg1: segmentation mask, arg2: radius of morphological structuring
            % element for erosion and dilation
            [bwLabelIntervein] = interveinMorphFilter(segmentationMask, morphFilterSizeIntervein);

            %% Morphological operation to filter the overall wing blade area
            [bwWing, bwLabelWing] = wingMorphFilter(segmentationMask, morphFilterSizwWing);
            % Function details: wingMorphFilter(arg1, arg2);
            % arg1: segmentation mask, arg2: radius of morphological structuring
            % element for erosion and dilation

            %% Classification of interveins using the geometrical features
            [bwLabelNew, yfit] = classificationInterveinGeometric(bwLabelIntervein);
            % Printing the labelled wing image
            imwrite(label2rgb(bwLabelNew),strcat('individual_output_files/', labelName));
            save('individual_output_files/bwLabelNew.mat','bwLabelNew');


            %% Area of interveins and wing blade area
            %[o1, o2, o3] = statsAreaTrichome(i1, i2, i3);
            % Output:
            % a) o1: area of individual labelled intervein regions
            % b) o2: trichomedensity of each individual intervein region
            % c) o3: spatial location of each intervein
            % Input:
            % a) i1: raw image data
            % b) i2: labelled interveins 
            % c) i3: thresholding filter of individual trichomes
            [areaIntervein, trichomeIntervein, hairSpace] = statsAreaTrichome(rawImage, bwLabelNew, thresholdTrichome);
            trichomeDensityIntervein = trichomeIntervein' ./ areaIntervein; 
            
            % Global statistics of wing blade
            statsCompleteWing = regionprops(bwWing,'Area');
            wingBladeArea = cat(1,statsCompleteWing.Area);
            trichomeDensityWing = sum(trichomeIntervein) / (magWing*magWing*(sum(areaIntervein)));
            
            app.WingareammTextArea.Value = num2str(wingBladeArea*magWing*magWing);
            app.TrichomedensitycountmmTextArea.Value = num2str(trichomeDensityWing);
            
            % Creating table of properties
            sortedyfit = sort(yfit);
            T = table(sortedyfit,areaIntervein*magWing*magWing,trichomeIntervein','VariableNames',{'ID','Area','# Trichomes'} );
            app.UITable.Data = T;
            
            % Plotting heatmap of intervein area
            areaLabel = bwLabelNew;
            for i = 1: length(yfit)
                areaLabel(areaLabel == yfit(i)) = areaIntervein(yfit(i));
            end
            
            imshow(label2rgb(bwLabelNew),'Parent',app.UIAxes2);
            % imagesc(areaLabel,'Parent',app.UIAxes)
            % c = colorbar(app.UIAxes);
        end

        % Button pushed function: LoadRawImageButton
        function LoadRawImageButtonPushed(app, event)
            global individualLabel
            [rawImage, imagePath] = uigetfile('*.*');
            fullFilePath = fullfile(imagePath, rawImage);
            if app.EnableuniqueoutputlabelCheckBox.Value
                individualLabel = strcat(rawImage(1:end-4), "_Label.png");
            else
                individualLabel = "imageOutput.png";
            end
            rawImage = imread(fullFilePath);
            imshow(rawImage,'Parent',app.UIAxes);
            save('individual_output_files/rawImage.mat','rawImage');  
        end

        % Button pushed function: LoadSegmentationMaskButton
        function LoadSegmentationMaskButtonPushed(app, event)
            [segmentationMask, imagePath] = uigetfile('*.*');
            fullFilePath = fullfile(imagePath, segmentationMask);
            segmentationMask = imread(fullFilePath);
            save('individual_output_files/segmentationMask.mat','segmentationMask'); 
        end

        % Button pushed function: APaxisButton
        function APaxisButtonPushed(app, event)
            addpath('dependencies');
            
            load('individual_output_files/rawImage.mat');
            load('individual_output_files/bwLabelNew.mat');
            hold(app.UIAxes3, "off"); % FH EDIT
            app.UIAxes3.cla; % FH EDIT
            
            [veinPositions] = veinNormal(bwLabelNew);
            magWing = app.ConversionfactorEditField.Value;
            % Calculating veinPositions of the L1 and L4 vein for estimating the AP
            % axis
            centroidLabelStr = regionprops(bwLabelNew,'centroid');
            centroidLabel = cat(1,centroidLabelStr.Centroid);
            centroidI3 = centroidLabel(3,:);
            
            L2Points1 = [veinPositions(1,1) veinPositions(1,2)];
            L2Points2 = [veinPositions(1,3) veinPositions(1,4)];
            L5Points1 = [veinPositions(4,1) veinPositions(4,2)];
            L5Points2 = [veinPositions(4,3) veinPositions(4,4)];
            
            if norm(centroidI3 - L2Points1) > norm(centroidI3 - L2Points2)
                L2PointAP = L2Points1;
            else
                L2PointAP = L2Points2;
            end

            if norm(centroidI3 - L5Points1) > norm(centroidI3 - L5Points2)
                L5PointAP = L5Points1;
            else
                L5PointAP = L5Points2;
            end
            
            APPoints = vertcat(L2PointAP,L5PointAP);
            
            % Finding the length of AP axis
            lengthAP = norm(L2PointAP-L5PointAP);
            app.EditField.Value = num2str(lengthAP*magWing);
            
            % Plotting the AP axis
            imshow(rawImage,'Parent',app.UIAxes3);
            hold(app.UIAxes3);
            plot(app.UIAxes3, APPoints(:,1), APPoints(:,2),'--rs',...
                'LineWidth',2,...
                'MarkerSize',10,...
                'MarkerEdgeColor','b',...
                'MarkerFaceColor',[0.5,0.5,0.5]);
        end

        % Button pushed function: dL3L4Button
        function dL3L4ButtonPushed(app, event)
            addpath('dependencies');
            
            load('individual_output_files/rawImage.mat');
            load('individual_output_files/bwLabelNew.mat');
            hold(app.UIAxes3, "off"); % FH EDIT
            app.UIAxes3.cla; % FH EDIT

            
            [veinPositions] = veinNormal(bwLabelNew);
            magWing = app.ConversionfactorEditField.Value;
            
            centroidLabelStr = regionprops(bwLabelNew,'centroid');
            centroidLabel = cat(1,centroidLabelStr.Centroid);
            centroidI3 = centroidLabel(3,:);            
            
            
            L3Points1 = [veinPositions(2,1) veinPositions(2,2)];
            L3Points2 = [veinPositions(2,3) veinPositions(2,4)];
            L4Points1 = [veinPositions(3,1) veinPositions(3,2)];
            L4Points2 = [veinPositions(3,3) veinPositions(3,4)];
            
            
            if norm(centroidI3 - L3Points1) > norm(centroidI3 - L3Points2)
                L3PointRight = L3Points1;
                L3PointLeft = L3Points2;
            else
                L3PointRight = L3Points2;
                L3PointLeft = L3Points1;
            end
            
            if norm(centroidI3 - L4Points1) > norm(centroidI3 - L4Points2)
                L4PointRight = L4Points1;
                L4PointLeft = L4Points2;
            else
                L4PointRight = L4Points2;
                L4PointLeft = L4Points1;
            end
            
            L3L4PointsRight = vertcat(L3PointRight,L4PointRight);
            
            % Distance between L3 and L4
            lengthL3L4 = norm(L3PointRight - L4PointRight);
            app.EditField.Value = num2str(lengthL3L4*magWing);
            
            % Visualizing the L3 L4 measure taken
            imshow(rawImage,'Parent',app.UIAxes3);
            hold(app.UIAxes3);
            plot(app.UIAxes3,L3L4PointsRight(:,1), L3L4PointsRight(:,2),'--rs',...
                'LineWidth',2,...
                'MarkerSize',10,...
                'MarkerEdgeColor','b',...
                'MarkerFaceColor',[0.5,0.5,0.5])

        end

        % Button pushed function: PDaxisButton
        function PDaxisButtonPushed(app, event)
            addpath('dependencies');
            
            load('individual_output_files/rawImage.mat');
            load('individual_output_files/bwLabelNew.mat');
            hold(app.UIAxes3, "off"); % FH EDIT
            app.UIAxes3.cla; % FH EDIT
            
            [veinPositions] = veinNormal(bwLabelNew); 
            magWing = app.ConversionfactorEditField.Value;

            centroidLabelStr = regionprops(bwLabelNew,'centroid');
            centroidLabel = cat(1,centroidLabelStr.Centroid);
            centroidI3 = centroidLabel(3,:);            
            
            
            L3Points1 = [veinPositions(2,1) veinPositions(2,2)];
            L3Points2 = [veinPositions(2,3) veinPositions(2,4)];
            L4Points1 = [veinPositions(3,1) veinPositions(3,2)];
            L4Points2 = [veinPositions(3,3) veinPositions(3,4)];
            
            
            if norm(centroidI3 - L3Points1) > norm(centroidI3 - L3Points2)
                L3PointRight = L3Points1;
                L3PointLeft = L3Points2;
            else
                L3PointRight = L3Points2;
                L3PointLeft = L3Points1;
            end
            
            if norm(centroidI3 - L4Points1) > norm(centroidI3 - L4Points2)
                L4PointRight = L4Points1;
                L4PointLeft = L4Points2;
            else
                L4PointRight = L4Points2;
                L4PointLeft = L4Points1;
            end
            
            L3L4PointsRight = vertcat(L3PointRight,L4PointRight);            
            L3L4PointsLeft = vertcat(L3PointLeft,L4PointLeft);

            pointsPD = vertcat(mean(L3L4PointsLeft),mean(L3L4PointsRight));
            
            % Length of PD axis
            lengthPD = norm( (mean(L3L4PointsRight)) - (mean(L3L4PointsLeft)));
            app.EditField.Value = num2str(lengthPD*magWing);
            
            % Visualizing the PD axis
            
            imshow(rawImage,'Parent',app.UIAxes3);
            hold(app.UIAxes3);
            plot(app.UIAxes3,pointsPD(:,1), pointsPD(:,2),'--rs',...
                'LineWidth',2,...
                'MarkerSize',10,...
                'MarkerEdgeColor','b',...
                'MarkerFaceColor',[0.5,0.5,0.5]);
        end

        % Button pushed function: BatchProcessingButton
        function BatchProcessingButtonPushed(app, event)
            format long g;
            format compact;
            addpath('dependencies');
            addpath(genpath('userData'));
            
            magWing = app.ConversionfactorEditField.Value;
            %% Parameter definition
            morphFilterSizeIntervein = app.SizeofinterveinfilterEditField.Value; % 5
            morphFilterSizwWing = app.SizeofwingfilterEditField.Value; % 8
            thresholdTrichome = app.ThresholddistancetrichomeEditField.Value; %2
            numHarmonics = app.NumberofharmonicsEditField.Value; %20 
            
            %% Getting user define path for the folders containing the wing images to be analyzed
            start_path = fullfile(matlabroot, 'C:\Users\Nilay\Desktop\AutomatedWingAnalysis\GPCR\Galphasubunits');
            topLevelFolder = uigetdir(start_path);
            if topLevelFolder == 0
            	return;
            end
            allSubFolders = genpath(topLevelFolder);
            remain = allSubFolders;
            listOfFolderNames = {};
            while true
            	[singleSubFolder, remain] = strtok(remain, ';');
            	if isempty(singleSubFolder)
            		break;
            	end
            	listOfFolderNames = [listOfFolderNames singleSubFolder];
            end
            %%%%% FH EDIT %%%%
            idx_Cropped = contains(listOfFolderNames, 'croppedWings'); % Obtain logical array checking if any folders contain cropped wings
            if any(idx_Cropped)
                listOfFolderNames = {listOfFolderNames{idx_Cropped}}; % If logical array does contain cropped, look for images only in cropped wings folder
            end
            %%%%%%%%%%%%%%%%%%
            numberOfFolders = length(listOfFolderNames);
            
            %% Looping through each folder and associated wing images for feature extraction
            
            wingAnalysisCounter = 1;
            folderAnalysisCounter = 1;
            
            % looping through the folders containing wing images
            for k = 1 : numberOfFolders
            	thisFolder = listOfFolderNames{k};
            	fprintf('Processing folder %s\n', thisFolder);
             	filePattern = sprintf('%s/*.tif', thisFolder);
                baseFileNames = dir(filePattern);
               	numberOfImageFiles = length(baseFileNames);
             	if numberOfImageFiles >= 1       
                    % looping through each file present in an individual folder
             		for f = 1 : numberOfImageFiles
             			fullFileName = fullfile(thisFolder, baseFileNames(f).name); % Name of the .TIF
                        [filepath,name,ext] = fileparts(fullFileName);
                        
                        % Getting the file name without the extension
                        fullFileName2 = fullFileName(1:end-4);
                        
                        % Creating filenames for the segmentation mask
                        fullFileNameMask = strcat(fullFileName2,'_Simple Segmentation.png');
                        
                        % Creating filenames for the labelled interveins
                        fullFileNameLabel = strcat(fullFileName2,'_Label.jpg');
                        
                        % Reading in the images and corresponding segmentation masks
                        rawImage = imread(fullFileName); % Should read in the cropped wing
                        segmentationMask = imread(fullFileNameMask); % Should read in the segmentation Mask
                        
                        % Morphometric filter for filtering the interveins
                        [bwLabelIntervein] = interveinMorphFilter(segmentationMask, morphFilterSizeIntervein);
                        
                        % Morphometric filter for extracting the global wing shape
                        [bwWing, bwLabelWing] = wingMorphFilter(segmentationMask, morphFilterSizwWing);
                        
                        % Classifying the interveins
                        dummyLabel = [];
                        dummyLabel = bwLabelIntervein;
                        yfit = [];
                        [bwLabelNew, yfit] = classificationInterveinGeometric(dummyLabel);
                        
                        % Printing the labelled intervein areas as a file
                        imwrite(label2rgb(bwLabelNew),fullFileNameLabel);
                        
                        % Storing the filenames and genotypes of each individual wing
                        filesName{wingAnalysisCounter} = strcat(name);
                        folderName(wingAnalysisCounter) = folderAnalysisCounter;
                        
                        % Area and trichome numbers of individual intervein regions
                        areaIntervein = [];
                        trichomeIntervein = [];
                        hairSpace = [];
                        [areaIntervein, trichomeIntervein, hairSpace] = statsAreaTrichome(rawImage, bwLabelIntervein, thresholdTrichome);
                        statsAreaIntervein(wingAnalysisCounter,1:10) = 0;
                        statsTrichomeIntervein(wingAnalysisCounter,1:10) = 0;
                        for i = 1:max(max(bwLabelIntervein))
                            statsAreaIntervein(wingAnalysisCounter,yfit(i)) = areaIntervein(i);
                        end
                        for i = 1:max(max(bwLabelIntervein))
                            statsTrichomeIntervein(wingAnalysisCounter,yfit(i)) = trichomeIntervein(i);
                        end
                        
                        % Global statistics of wing
                        statsCompleteWing = [];
                        statsCompleteWing = regionprops(bwWing,'Area');
                        statswingBladeArea(wingAnalysisCounter) = cat(1,statsCompleteWing.Area);
                        statstrichomeDensityWing(wingAnalysisCounter) = sum(trichomeIntervein) / sum(areaIntervein);
                        
                        % Extracting elliptic fourier descriptor  coefficients 
                        EFDCoefficients{wingAnalysisCounter} = efdCoeffWing(bwWing, numHarmonics);
                        statsEFDReshaped(wingAnalysisCounter,:) = reshape(EFDCoefficients{wingAnalysisCounter},[1 numHarmonics*4]);
            
                        
                        % Extracting AP, PD nad d(L3,L4) for normal looking wings
                        yfitReshaped = [];
                        yfitReshaped = reshape(yfit,[1,length(yfit)]);
                        checkArray = [1,2,3,4,5,6,7];
                        if isequal(sort(yfitReshaped), checkArray) == 1
                            veinPositions = [];
                            % Extracting vein position
                            [veinPositions] = veinNormal(bwLabelNew);
                            % Extract intervein 3 centroid for identication of distal
                            % and proximal end of veins
                            centroidLabelStr = [];
                            centroidLabel = [];
                            centroidLabelStr = regionprops(bwLabelNew,'centroid');
                            centroidLabel = cat(1,centroidLabelStr.Centroid);
                            centroidI3 = centroidLabel(3,:);
                            
                            % calculating AP distance
                            L2Points1 = [veinPositions(1,1) veinPositions(1,2)];
                            L2Points2 = [veinPositions(1,3) veinPositions(1,4)];
                            L5Points1 = [veinPositions(4,1) veinPositions(4,2)];
                            L5Points2 = [veinPositions(4,3) veinPositions(4,4)];
                            if norm(centroidI3 - L2Points1) > norm(centroidI3 - L2Points2)
                                L2PointAP = L2Points1;
                            else
                                L2PointAP = L2Points2;
                            end
            
                            if norm(centroidI3 - L5Points1) > norm(centroidI3 - L5Points2)
                                L5PointAP = L5Points1;
                            else
                                L5PointAP = L5Points2;
                            end
                            lengthAP(wingAnalysisCounter) = norm(L2PointAP-L5PointAP);
                           
                            
                            % calculating distance between L3 and L4          
                            L3Points1 = [veinPositions(2,1) veinPositions(2,2)];
                            L3Points2 = [veinPositions(2,3) veinPositions(2,4)];
                            L4Points1 = [veinPositions(3,1) veinPositions(3,2)];
                            L4Points2 = [veinPositions(3,3) veinPositions(3,4)];
                            if norm(centroidI3 - L3Points1) > norm(centroidI3 - L3Points2)
                                L3PointRight = L3Points1;
                                L3PointLeft = L3Points2;
                            else
                                L3PointRight = L3Points2;
                                L3PointLeft = L3Points1;
                            end
            
                            if norm(centroidI3 - L4Points1) > norm(centroidI3 - L4Points2)
                                L4PointRight = L4Points1;
                                L4PointLeft = L4Points2;
                            else
                                L4PointRight = L4Points2;
                                L4PointLeft = L4Points1;
                            end
                            lengthL3L4(wingAnalysisCounter) = norm(L3PointRight - L4PointRight);
                            
                            % calculating PD distance                
                            L3L4PointsRight = vertcat(L3PointRight,L4PointRight);
                            L3L4PointsLeft = vertcat(L3PointLeft,L4PointLeft);
                            lengthPD(wingAnalysisCounter) = norm( (mean(L3L4PointsRight)) - (mean(L3L4PointsLeft)));
                        else
                            lengthAP(wingAnalysisCounter) = 0;
                            lengthPD(wingAnalysisCounter) = 0;
                            lengthL3L4(wingAnalysisCounter) = 0;
                        end
                        
                        % increaing the counter of processing wing image files
                        wingAnalysisCounter = wingAnalysisCounter+1;
                        fprintf('Processing image file %s\n', fullFileName);
             		end
             	else
             		fprintf('     Folder %s has no image files in it.\n', thisFolder);
                end
                folderAnalysisCounter = folderAnalysisCounter + 1;
            end
            
            % Creating table with all the features
            tempName = app.OutputspreadsheetsavenameEditField.Value;
            varNames = {'Wing name','Genotype','Intervein area','Intervein trichome count','Total wing area', 'Total trichome density','AP axis', 'PD axis', 'd(L3-L4)'};
            T = table(filesName',folderName',statsAreaIntervein*magWing*magWing,statsTrichomeIntervein,statswingBladeArea'*magWing*magWing,statstrichomeDensityWing'/(magWing*magWing),lengthAP'*magWing,lengthPD'*magWing,lengthL3L4'*magWing,'VariableNames',varNames);
            save(strcat('batch_output_files/', tempName, '_Wing_Measurements_Output.mat'),'T');
            N = size(statsEFDReshaped,2);
            C = cell(N,1);
            
            % Save outputs as .csv files
            for i = 1:N
                C{i} = ['Coefficient' num2str(i)];
            end
            EFDNames = table2cell([{'Wing name', 'Genotype'}, cell2table(C')]);
            statsEFDReshaped = cell2table([filesName' table2cell(array2table(folderName))' table2cell(array2table(statsEFDReshaped))],'VariableNames',EFDNames);
            save(strcat('batch_output_files/', tempName,'_EFD_Output.mat'), 'statsEFDReshaped');
            EFDBatch = load(strcat(tempName, '_EFD_Output.mat'));
            writetable(EFDBatch.statsEFDReshaped, strcat('batch_output_files/', tempName, '_EFD_Output.csv'));
            writetable(T, strcat('batch_output_files/', tempName, '_Wing_Measurements_Output.csv'));
            fprintf('\n');
            disp("%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%");
            disp("Batch processing complete!");
        end

        % Button pushed function: RemovewinghingeButton
        function RemovewinghingeButtonPushed(app, event)
            % FH EDIT: Allow remove wing hinge functionality
            myFolder = uigetdir;
            WingHingeRemoval;
        end

        % Button pushed function: FullProcessButton
        function FullProcessButtonPushed(app, event)
            global individualLabel
            [rawImage, imagePath] = uigetfile('*.*');
            fullFilePath = fullfile(imagePath, rawImage);
            if app.EnableuniqueoutputlabelCheckBox.Value
                individualLabel = strcat(rawImage(1:end-4), "_Label.png");
            else
                individualLabel = "imageOutput.png";
            end
            rawImage = imread(fullFilePath);
            imshow(rawImage,'Parent',app.UIAxes);
            save('individual_output_files/rawImage.mat','rawImage');  
            segmentationMask = imread(strcat(fullFilePath(1:end-4),'_Simple Segmentation.png'));  
            save('individual_output_files/segmentationMask.mat','segmentationMask');   
            ClassifyInterveinsButtonPushed(app, @ClassifyinterveinsButtonPushed);
            APaxisButtonPushed(app, @APaxisButtonPushed);
        end

        % Button pushed function: SeeGitHubPageButton
        function SeeGitHubPageButtonPushed(app, event)
            web("https://multicellularsystemslab.github.io/MAPPER/");
        end

        % Button pushed function: OpenWingMeasurementsButton
        function OpenWingMeasurementsButtonPushed(app, event)
            tempName = app.OutputspreadsheetsavenameEditField.Value;
            fileToOpen = strcat('batch_output_files/', tempName,'_Wing_Measurements_Output.csv');
            if (ispc)
                winopen(fileToOpen);
            elseif ismac
                cmdToExecute = ['open ' fileToOpen];
                [status, path] = system(cmdToExecute);
            else
                cmdToExecute = ['xdg-open ' fileToOpen];
                [status, path] = system(cmdToExecute);
            end
        end

        % Button pushed function: OpenEFDCoefficientsButton
        function OpenEFDCoefficientsButtonPushed(app, event)
            tempName = app.OutputspreadsheetsavenameEditField.Value;
            fileToOpen = strcat('batch_output_files/', tempName, '_EFD_Output.csv');
            if (ispc)
                winopen(fileToOpen);
            elseif ismac
                cmdToExecute = ['open ' fileToOpen];
                [status, path] = system(cmdToExecute);
            else
                cmdToExecute = ['xdg-open ' fileToOpen];
                [status, path] = system(cmdToExecute);
            end
        end

        % Button pushed function: OpenManualButton
        function OpenManualButtonPushed(app, event)
            fileToOpen = 'MAPPER_manual.pdf';
            if (ispc)
                winopen(fileToOpen);
            elseif ismac
                cmdToExecute = ['open ' fileToOpen];
                [status, path] = system(cmdToExecute);
            else
                cmdToExecute = ['xdg-open ' fileToOpen];
                [status, path] = system(cmdToExecute);
            end
        end

        % Button pushed function: SubmitTroubleshootInquiryButton
        function SubmitTroubleshootInquiryButtonPushed(app, event)
            web("https://docs.google.com/forms/d/e/1FAIpQLSf0BW6Fst_2810m7jEWvt0I2NqAQ61VX_0RrQxAp8buXahr-w/viewform?usp=pp_url");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ClassifyInterveinsButton = uibutton(app.UIFigure, 'push');
            app.ClassifyInterveinsButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyInterveinsButtonPushed, true);
            app.ClassifyInterveinsButton.Tooltip = {'Classify the interveins and obtain output measurements.'};
            app.ClassifyInterveinsButton.Text = 'Classify Interveins';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Loaded raw image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Labeled output image')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ID'; 'Area'; '# Trichomes'; 'Column 4'};
            app.UITable.RowName = {};
            app.WingareammLabel = uilabel(app.UIFigure);
            app.WingareammLabel.Tooltip = {'Total wing area of the processed wing.'};
            app.WingareammLabel.Text = 'Wing area (mm  ):';
            app.WingareammTextArea = uitextarea(app.UIFigure);
            app.TrichomedensitycountmmLabel = uilabel(app.UIFigure);
            app.TrichomedensitycountmmLabel.Tooltip = {'Total trichome density of the processed wing.'};
            app.TrichomedensitycountmmLabel.Text = 'Trichome density (count/mm  ):';
            app.TrichomedensitycountmmTextArea = uitextarea(app.UIFigure);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Biological axes display')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.LoadRawImageButton = uibutton(app.UIFigure, 'push');
            app.LoadRawImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRawImageButtonPushed, true);
            app.LoadRawImageButton.Tooltip = {'Load a .TIF file with the wing hinge removed.'};
            app.LoadRawImageButton.Text = 'Load Raw Image';
            app.LoadSegmentationMaskButton = uibutton(app.UIFigure, 'push');
            app.LoadSegmentationMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSegmentationMaskButtonPushed, true);
            app.LoadSegmentationMaskButton.Tooltip = {'Load the corresponding ILASTIK segmentation mask.'};
            app.LoadSegmentationMaskButton.Text = 'Load Segmentation Mask';
            app.APaxisButton = uibutton(app.UIFigure, 'push');
            app.APaxisButton.ButtonPushedFcn = createCallbackFcn(app, @APaxisButtonPushed, true);
            app.APaxisButton.Tooltip = {'Anterior to posterior axis length.'};
            app.APaxisButton.Text = 'AP axis';
            app.dL3L4Button = uibutton(app.UIFigure, 'push');
            app.dL3L4Button.ButtonPushedFcn = createCallbackFcn(app, @dL3L4ButtonPushed, true);
            app.dL3L4Button.Tooltip = {'Distance between longitudinal veins 3 and 4.'};
            app.dL3L4Button.Text = 'd(L3, L4)';
            app.PDaxisButton = uibutton(app.UIFigure, 'push');
            app.PDaxisButton.ButtonPushedFcn = createCallbackFcn(app, @PDaxisButtonPushed, true);
            app.PDaxisButton.Tooltip = {'Proximal to distal axis length.'};
            app.PDaxisButton.Text = 'PD axis';
            app.SizeofwingfilterEditFieldLabel = uilabel(app.UIFigure);
            app.SizeofwingfilterEditFieldLabel.Tooltip = {'Structuring element radius for erosion and dilation operations. A higher value should be used for images taken at greater magnification to properly label intervein regions.'};
            app.SizeofwingfilterEditFieldLabel.Text = 'Size of wing filter';
            app.SizeofwingfilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.SizeofwingfilterEditField.Value = 8;
            app.SizeofinterveinfilterEditFieldLabel = uilabel(app.UIFigure);
            app.SizeofinterveinfilterEditFieldLabel.Tooltip = {'Structuring element radius for erosion and dilation operations. A higher value should be used for images taken at greater magnification to properly label veins.'};
            app.SizeofinterveinfilterEditFieldLabel.Text = 'Size of intervein filter';
            app.SizeofinterveinfilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.SizeofinterveinfilterEditField.Value = 5;
            app.ThresholddistancetrichomeEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholddistancetrichomeEditFieldLabel.Tooltip = {'Minimum tolerable distance between two trichomes to prevent double counting. A higher value should be used for images taken at greater magnification.'};
            app.ThresholddistancetrichomeEditFieldLabel.Text = 'Threshold distance trichome';
            app.ThresholddistancetrichomeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholddistancetrichomeEditField.Value = 2;
            app.NumberofharmonicsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofharmonicsEditFieldLabel.Tooltip = {'Number of harmonics in the Fourier series to approximate the wing compartment countours. '};
            app.NumberofharmonicsEditFieldLabel.Text = 'Number of harmonics';
            app.NumberofharmonicsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofharmonicsEditField.Value = 15;
            app.BatchProcessingButton = uibutton(app.UIFigure, 'push');
            app.BatchProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @BatchProcessingButtonPushed, true);
            app.BatchProcessingButton.Tooltip = {'Process many wings at once.'};
            app.BatchProcessingButton.Text = 'Batch Processing';
            app.IIInputparametersLabel = uilabel(app.UIFigure);
            app.IIInputparametersLabel.Text = 'II. Input parameters ';
            app.GlobalstatisticsLabel = uilabel(app.UIFigure);
            app.GlobalstatisticsLabel.Text = 'Global statistics';
            app.UseroperationLabel = uilabel(app.UIFigure);
            app.UseroperationLabel.Text = 'User operation';
            app.BiologicalaxesLabel = uilabel(app.UIFigure);
            app.BiologicalaxesLabel.Text = 'Biological axes';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.BiologicalaxesLabel_2 = uilabel(app.UIFigure);
            app.BiologicalaxesLabel_2.Text = 'Biological axes';
            app.IIIIndividualwinganalysisLabel = uilabel(app.UIFigure);
            app.IIIIndividualwinganalysisLabel.Text = 'III.   Individual wing analysis';
            app.IVBatchprocessingLabel = uilabel(app.UIFigure);
            app.IVBatchprocessingLabel.Text = 'IV.    Batch processing';
            app.ConversionfactorEditFieldLabel = uilabel(app.UIFigure);
            app.ConversionfactorEditFieldLabel.Tooltip = {'Length scale conversion factor of mictrons per pixel of your raw images.'};
            app.ConversionfactorEditFieldLabel.Text = 'Conversion factor';
            app.ConversionfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConversionfactorEditField.Value = 0.002;
            app.UseroperationLabel_2 = uilabel(app.UIFigure);
            app.UseroperationLabel_2.Text = 'User operation';
            app.MulticellularAnalysisProcessingPlatformLabel = uilabel(app.UIFigure);
            app.MulticellularAnalysisProcessingPlatformLabel.Text = 'Multicellular Analysis Processing Platform';
            app.forExperimentalResearchLabel = uilabel(app.UIFigure);
            app.forExperimentalResearchLabel.Text = 'for Experimental Research';
            app.forimagingat4xLabel = uilabel(app.UIFigure);
            app.forimagingat4xLabel.Text = '8 for imaging at 4x';
            app.forimagingat4xLabel_2 = uilabel(app.UIFigure);
            app.forimagingat4xLabel_2.Text = '5 for imaging at 4x';
            app.forimagingat4xLabel_3 = uilabel(app.UIFigure);
            app.forimagingat4xLabel_3.Text = '2 for imaging at 4x';
            app.between1020Label = uilabel(app.UIFigure);
            app.between1020Label.Text = 'between 10-20';
            app.micronsperpixelLabel = uilabel(app.UIFigure);
            app.micronsperpixelLabel.Text = 'microns per pixel';
            app.IndividualandbatchLabel = uilabel(app.UIFigure);
            app.IndividualandbatchLabel.Text = 'Individual and batch';
            app.IndividualandbatchLabel_2 = uilabel(app.UIFigure);
            app.IndividualandbatchLabel_2.Text = 'Individual and batch';
            app.IndividualandbatchLabel_3 = uilabel(app.UIFigure);
            app.IndividualandbatchLabel_3.Text = 'Individual and batch';
            app.IndividualandbatchLabel_4 = uilabel(app.UIFigure);
            app.IndividualandbatchLabel_4.Text = 'Individual and batch';
            app.BatchonlyLabel = uilabel(app.UIFigure);
            app.BatchonlyLabel.Text = 'Batch only';
            app.IWingpreprocessingLabel = uilabel(app.UIFigure);
            app.IWingpreprocessingLabel.Text = 'I. Wing pre-processing';
            app.RemovewinghingeButton = uibutton(app.UIFigure, 'push');
            app.RemovewinghingeButton.ButtonPushedFcn = createCallbackFcn(app, @RemovewinghingeButtonPushed, true);
            app.RemovewinghingeButton.Tooltip = {'Removes the wing hinge from uncropped raw images.'};
            app.RemovewinghingeButton.Text = 'Remove wing hinge';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.FullProcessButton = uibutton(app.UIFigure, 'push');
            app.FullProcessButton.ButtonPushedFcn = createCallbackFcn(app, @FullProcessButtonPushed, true);
            app.FullProcessButton.Tooltip = {'Performs the functions of all three buttons to the left.'};
            app.FullProcessButton.Text = {'Full'; 'Process'};
            app.IndividualinterveinoutputLabel = uilabel(app.UIFigure);
            app.IndividualinterveinoutputLabel.Tooltip = {'Measurement outputs of the individually processed wing.'};
            app.IndividualinterveinoutputLabel.Text = 'Individual intervein output';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'GitHub-logo.png';
            app.TroubleshootingLabel = uilabel(app.UIFigure);
            app.TroubleshootingLabel.Text = 'Troubleshooting';
            app.SeeGitHubPageButton = uibutton(app.UIFigure, 'push');
            app.SeeGitHubPageButton.ButtonPushedFcn = createCallbackFcn(app, @SeeGitHubPageButtonPushed, true);
            app.SeeGitHubPageButton.Tooltip = {'Opens the GitHub page for this project.'};
            app.SeeGitHubPageButton.Text = 'See GitHub Page';
            app.OutputspreadsheetsavenameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputspreadsheetsavenameEditFieldLabel.Tooltip = {'Save name for the batch processing output files.'};
            app.OutputspreadsheetsavenameEditFieldLabel.Text = 'Output spreadsheet save name';
            app.OutputspreadsheetsavenameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputspreadsheetsavenameEditField.Value = 'Demo';
            app.OpenWingMeasurementsButton = uibutton(app.UIFigure, 'push');
            app.OpenWingMeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenWingMeasurementsButtonPushed, true);
            app.OpenWingMeasurementsButton.Tooltip = {'Open the output measurements .csv file of the spreadsheet named above.'};
            app.OpenWingMeasurementsButton.Text = 'Open Wing Measurements';
            app.OpenEFDCoefficientsButton = uibutton(app.UIFigure, 'push');
            app.OpenEFDCoefficientsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenEFDCoefficientsButtonPushed, true);
            app.OpenEFDCoefficientsButton.Tooltip = {'Open the output EFD values .csv file of the spreadsheet named above.'};
            app.OpenEFDCoefficientsButton.Text = 'Open EFD Coefficients';
            app.OpenManualButton = uibutton(app.UIFigure, 'push');
            app.OpenManualButton.ButtonPushedFcn = createCallbackFcn(app, @OpenManualButtonPushed, true);
            app.OpenManualButton.Tooltip = {'Open the MAPPER manual PDF.'};
            app.OpenManualButton.Text = 'Open Manual';
            app.SubmitTroubleshootInquiryButton = uibutton(app.UIFigure, 'push');
            app.SubmitTroubleshootInquiryButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitTroubleshootInquiryButtonPushed, true);
            app.SubmitTroubleshootInquiryButton.Tooltip = {'Submit a Google Form troubleshoot request.'};
            app.SubmitTroubleshootInquiryButton.Text = 'Submit Troubleshoot Inquiry';
            app.ProcessingapplicabilityLabel = uilabel(app.UIFigure);
            app.ProcessingapplicabilityLabel.Text = 'Processing applicability';
            app.DefaultvalueLabel = uilabel(app.UIFigure);
            app.DefaultvalueLabel.Text = 'Default value:';
            app.DefaultvalueLabel_2 = uilabel(app.UIFigure);
            app.DefaultvalueLabel_2.Text = 'Default value:';
            app.DefaultvalueLabel_3 = uilabel(app.UIFigure);
            app.DefaultvalueLabel_3.Text = 'Default value:';
            app.DefaultvalueLabel_4 = uilabel(app.UIFigure);
            app.DefaultvalueLabel_4.Text = 'Default value:';
            app.ScalinginterpretationLabel = uilabel(app.UIFigure);
            app.ScalinginterpretationLabel.Text = 'Scaling interpretation:';
            app.EnableuniqueoutputlabelCheckBox = uicheckbox(app.UIFigure);
            app.EnableuniqueoutputlabelCheckBox.Tooltip = {'Enable a unique save name in the individual output folder.'};
            app.EnableuniqueoutputlabelCheckBox.Text = 'Enable unique output label';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '2';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.LengthmmLabel = uilabel(app.UIFigure);
            app.LengthmmLabel.Tooltip = {'Length of the biological axis selected.'};
            app.LengthmmLabel.Text = 'Length (mm)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAPPER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end